classdef progtask11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        andEnhancementLabel  matlab.ui.control.Label
        EdgeDetectionandImageFilteringLabel  matlab.ui.control.Label
        Slider_2             matlab.ui.control.Slider
        DropDown_3           matlab.ui.control.DropDown
        DropDown_2           matlab.ui.control.DropDown
        DropDown             matlab.ui.control.DropDown
        Slider               matlab.ui.control.Slider
        ConvertColorButton   matlab.ui.control.Button
        EnhanceImageButton   matlab.ui.control.Button
        FilterImageButton    matlab.ui.control.Button
        EdgeDetectionButton  matlab.ui.control.Button
        LoadImageButton      matlab.ui.control.Button
        UIAxesOriginal       matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage 
        FilteredImage = []
    end
    
    properties (Access = public)
         EdgeDetectionDropDown matlab.ui.control.DropDown 
         ConvertColorDropDown  matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.jpg';'*.png';'*.bmp'}, 'Select an image file');
            if isequal(fileName, 0)
                return; % User canceled the dialog
            end
            app.OriginalImage = imread(fullfile(filePath, fileName));

            % Display the original image in UIAxesOriginal
            imshow(app.OriginalImage, 'Parent', app.UIAxesOriginal);

            % Display the processed image in UIAxes
            if ~isempty(app.FilteredImage)
                imshow(app.FilteredImage, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
            if ~isempty(app.OriginalImage)
                grayImage = rgb2gray(app.OriginalImage);
                edgeAlgorithm = app.DropDown.Value; % Use the DropDown component for edge algorithm selection
                switch edgeAlgorithm
                    case 'Sobel'
                        edgeImage = edge(grayImage, 'Sobel');
                    case 'Prewitt'
                        edgeImage = edge(grayImage, 'Prewitt');
                    case 'Canny'
                        threshold = app.Slider.Value;
                        edgeImage = edge(grayImage, 'Canny', threshold);
                end

                % Create an imshow object in the UIAxes to display the image
                imshow(edgeImage, 'Parent', app.UIAxes);
            else
                msgbox('Please load an image first.', 'Error', 'error');
            end
        end

        % Button pushed function: FilterImageButton
        function FilterImageButtonPushed(app, event)
            function gaussianSmoothImage(app, sigma)
                filterSize = [5, 5]; % You can adjust the filter size
                filteredImage = imgaussfilt(app.OriginalImage, sigma, 'FilterSize', filterSize);
    
                % Display the filtered image in the UIAxes
                imshow(filteredImage, 'Parent', app.UIAxes);
                app.FilteredImage = filteredImage;
            end
            if ~isempty(app.OriginalImage)
                % Get the selected filter method from the dropdown
                selectedFilterMethod = app.DropDown_3.Value;

                % Get the selected sigma value from Slider_2
                selectedSigma = app.Slider_2.Value;

                switch selectedFilterMethod
                    case 'Gaussian Smoothing '
                        % Call the Gaussian smoothing function with the selected sigma
                        gaussianSmoothImage(app, selectedSigma);
                    case 'Median Filtering'
                        % Define your median filter parameters
                        filterSize = [3, 3]; % Example: 3x3 filter size

                        % Apply median filtering separately to each color channel
                        filteredImage = app.OriginalImage;
                        for channel = 1:3
                            filteredImage(:, :, channel) = medfilt2(app.OriginalImage(:, :, channel), filterSize);
                        end
                    case 'Histogram Equalization'
                        filteredImage = histeq(app.OriginalImage);
                    otherwise
                        % Handle other cases if needed
                        filteredImage = app.OriginalImage; % No filtering
                end

                % Display the filtered image in the UIAxes
                imshow(filteredImage, 'Parent', app.UIAxes);
                app.FilteredImage = filteredImage;
            else
                msgbox('Please load an image first.', 'Error', 'error');
            end
        end

        % Button pushed function: EnhanceImageButton
        function EnhanceImageButtonPushed(app, event)
            if ~isempty(app.OriginalImage)
                enhancedImage = imadjust(app.OriginalImage, [0.3, 0.7], [0, 1]);
                imshow(enhancedImage, 'Parent', app.UIAxes);
            else
                msgbox('Please load an image first.', 'Error', 'error');
            end

         
        end

        % Button pushed function: ConvertColorButton
        function ConvertColorButtonPushed(app, event)
            function hsiImage = rgb2hsi(rgbImage)
                r = double(rgbImage(:, :, 1));
                g = double(rgbImage(:, :, 2));
                b = double(rgbImage(:, :, 3));

                % Calculate hue
                num = 0.5 * ((r - g) + (r - b));
                den = sqrt((r - g) .^ 2 + (r - b) .* (g - b));
                theta = acos(num ./ (den + eps));

                % Calculate saturation
                minRGB = min(min(r, g), b);
                saturation = 1 - 3 * minRGB ./ (r + g + b + eps);

                % Calculate intensity
                intensity = (r + g + b) / 3;

                % Scale hue to be in the range [0, 2*pi]
                h = theta;
                h(b > g) = 2 * pi - h(b > g);

                % Normalize hue to the range [0, 1]
                h = h / (2 * pi);

                hsiImage = cat(3, h, saturation, intensity);
            end
            if ~isempty(app.OriginalImage)
                targetColorSpace = app.DropDown_2.Value;
                switch targetColorSpace
                    case 'RGB'
                        convertedImage = rgb2hsv(app.OriginalImage);  % Already in RGB
                    case 'HSI'
                        convertedImage = rgb2hsi(app.OriginalImage); % Convert to HSI
                    case 'Lab'
                        convertedImage = rgb2lab(app.OriginalImage); % Convert to Lab
                    case 'YCbCr'
                        convertedImage = rgb2ycbcr(app.OriginalImage); % Convert to YCbCr
                    % Add more cases for other color spaces as needed
                end
                imshow(convertedImage, 'Parent', app.UIAxes);
            else
                msgbox('Please load an image first.', 'Error', 'error');
            end
        end

        % Callback function
        function ConverttoRGBButtonPushed(app, event)

        
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            sigmaValue = app.Slider_2.Value;
            app.Slider_2.Value = sigmaValue; % Update the UI display
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'output')
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesOriginal, 'input image')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.FilterImageButton = uibutton(app.UIFigure, 'push');
            app.FilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButtonPushed, true);
            app.FilterImageButton.Text = 'Filter Image';
            app.EnhanceImageButton = uibutton(app.UIFigure, 'push');
            app.EnhanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceImageButtonPushed, true);
            app.EnhanceImageButton.Text = 'Enhance Image';
            app.ConvertColorButton = uibutton(app.UIFigure, 'push');
            app.ConvertColorButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertColorButtonPushed, true);
            app.ConvertColorButton.Text = 'Convert Color';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 0.99];
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sobel', 'Prewitt', 'Canny'};
            app.DropDown.Value = 'Sobel';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'RGB', 'Lab', 'YCbCr', 'HSI'};
            app.DropDown_2.Value = 'Lab';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'Gaussian Smoothing ', 'Histogram Equalization', 'Median Filtering'};
            app.DropDown_3.Value = 'Gaussian Smoothing ';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [2 80];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 2;
            app.EdgeDetectionandImageFilteringLabel = uilabel(app.UIFigure);
            app.EdgeDetectionandImageFilteringLabel.Text = 'Edge Detection and Image Filtering';
            app.andEnhancementLabel = uilabel(app.UIFigure);
            app.andEnhancementLabel.Text = 'and Enhancement:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = progtask11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end